classdef DataCommitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        RunSummaryTab                 matlab.ui.container.Tab
        RunSummaryGridLayout          matlab.ui.container.GridLayout
        RunSummaryTable               matlab.ui.control.Table
        LapSummaryTab                 matlab.ui.container.Tab
        LapSummaryGridLayout          matlab.ui.container.GridLayout
        LapSummaryTable               matlab.ui.control.Table
        UserInputsGridLayout          matlab.ui.container.GridLayout
        RunDetailsPanel               matlab.ui.container.Panel
        CommitButton                  matlab.ui.control.Button
        DriverDropDown                matlab.ui.control.DropDown
        DriverDropDownLabel           matlab.ui.control.Label
        RunNumberEditField            matlab.ui.control.NumericEditField
        RunNumberEditFieldLabel       matlab.ui.control.Label
        VehicleModelPanel             matlab.ui.container.Panel
        TrackEditField                matlab.ui.control.EditField
        TrackEditFieldLabel           matlab.ui.control.Label
        VehicleModelEditField         matlab.ui.control.EditField
        VehicleModelEditFieldLabel    matlab.ui.control.Label
        RunDescriptionPanel           matlab.ui.container.Panel
        RunDescriptionEditField       matlab.ui.control.EditField
        RunDescriptionEditFieldLabel  matlab.ui.control.Label
        CSVFilepathPanel              matlab.ui.container.Panel
        ChooseCSVButton               matlab.ui.control.Button
        CSVFilepathEditField          matlab.ui.control.EditField
        CSVFilepathEditFieldLabel     matlab.ui.control.Label
        DataCommitterLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)

        csvFileName % csvFileName of the rFpro CSV

    end
    
    methods (Access = private)
        
        function app = createSummary(app, matFilePath)

            % Load in the runStruct
            load(matFilePath);

            % Create a dummy lap struct
            dummyLapStruct = struct();

            for i = 1:size(runStruct.metadata.laps, 2)

                dummyLapStruct(i).Year = string(runStruct.metadata.year); ...
                dummyLapStruct(i).Event = string(runStruct.metadata.event); ...
                dummyLapStruct(i).Day = string(runStruct.metadata.day); ...
                dummyLapStruct(i).Run = string(runStruct.metadata.runNumber); ...
                dummyLapStruct(i).Track = string(runStruct.metadata.track);
                dummyLapStruct(i).Driver = string(runStruct.metadata.driver);
                dummyLapStruct(i).LapNumber = runStruct.metadata.laps(i).lapNumber;
                dummyLapStruct(i).LapTime = runStruct.metadata.laps(i).lapTime;
                dummyLapStruct(i).LapType = runStruct.metadata.laps(i).lapType;
                dummyLapStruct(i).VehicleModel = string(runStruct.metadata.vehicleModel);

            end

            % Create a laps table
            lapsTable = struct2table(dummyLapStruct);

            % Create a run summary
            runSummaryCell = {

            'Year', string(runStruct.metadata.year); ...
            'Event', string(runStruct.metadata.event); ...
            'Day', string(runStruct.metadata.day); ...
            'Run', string(runStruct.metadata.runNumber); ...
            'Track', string(runStruct.metadata.track); ...
            'Driver', string(runStruct.metadata.driver); ...
            'Description', string(runStruct.metadata.description); ...
            'Laps', (size(runStruct.metadata.laps, 2)); ...
            'FileName', string(runStruct.metadata.runName); ...
            'VehicleModel', string(runStruct.metadata.vehicleModel); ...
            }';

            % Save this as a table
            runSummaryTable = cell2table(runSummaryCell(2,:), "VariableNames", runSummaryCell(1,:));

            % Update the tables in the app
            app.RunSummaryTable.Data = runSummaryTable;
            app.LapSummaryTable.Data = lapsTable;

        end
        
        function app = fnCheckNextRunNumber(app, csvFilePath)

            % Get the run name from the CSV
            [~, runName, ~] = fileparts(csvFilePath);

            % Store run date
            dateString = char(extractBetween(runName, '-', '_'));

            % Store the year
            year = dateString(1:4);

            % Convert dateString to dateNum
            dateNum = datenum(dateString, 'yyyymmdd');

            % Get Monday datenum for that week
            dayOfWeek = weekday(dateNum);
            if dayOfWeek == 2
                monday = dateNum;
            elseif dayOfWeek == 1

                % Get previous Monday if current day is Sunday
                monday = dateNum - (dayOfWeek - 2 + 7);

            else
                monday = dateNum - (dayOfWeek - 2);
            end

            % Format monday as mm_dd
            mondayDateString = datestr(monday, 'mm_dd');

            % Store the event
            event = ['FYP', mondayDateString];

            % Store the day (D1 = Monday, D7 = Sunday)
            day = dayOfWeek - 1;
            if day == 0
                day = 7;
            end
            day = sprintf('D%i', day);

            % Set the event folder
            eventFolder = fullfile('D:\Users\Saian\Workspace\Data\+ProcessedData', year, event);

            % Set the initial runID
            runNumber = 1;
            runID = sprintf('%s_%s_%s_R01', year, event, day);
            matFilePath = fullfile(eventFolder, strcat(runID, '.mat'));

            % Get the next run number by using a while loop, incrementing
            % the run number, and checking if that new file exists
            while isfile(matFilePath)

                % Increment the run number
                runNumber = runNumber + 1;

                % Format the run number as R0X or RXX depending on whether
                % the run number is single digit or not
                if runNumber < 10

                    runNumberString = sprintf('R0%i', runNumber);

                else

                    runNumberString = sprintf('R%i', runNumber);

                end

                % Re-format the runID
                runID = sprintf('%s_%s_%s_%s', year, event, day, runNumberString);

                % Re-format the mat filepath
                matFilePath = fullfile(eventFolder, strcat(runID, '.mat'));

                % Assign the next run number
                nextRunNumber = runNumber;

            end

            % Set the Run Number as the nextRunNumber
            app.RunNumberEditField.Value = nextRunNumber;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseCSVButton
        function ChooseCSVButtonPushed(app, event)
            
            % CSV Folder
            baseFolder = 'D:\rFpro_2024a\rFpro\CSVOutput';

            if ~isfolder(baseFolder)

                baseFolder = pwd;

            end

            % Land in the CSV Output folder and select a csv
            [filename, pathname] = uigetfile({'*.csv', 'CSV Files (*.csv)'; '*.*', 'All Files (*.*)'}, 'Select a CSV File', baseFolder);

            % Sort csv filepath
            csvFilePath = fullfile(pathname, filename);
            app.csvFileName = filename;

            % Update the edit field to reflect this
            app.CSVFilepathEditField.Value = csvFilePath;

            % Auto-Check the next run number
            app.fnCheckNextRunNumber(csvFilePath);
            

        end

        % Button pushed function: CommitButton
        function CommitButtonPushed(app, event)
            % Define backup folder
            backupFolder = 'C:\Users\Saian\OneDrive - Imperial College London\DE4\Masters\Data\Backup';

            % Copy the file over to one drive
            copyfile(app.CSVFilepathEditField.Value, fullfile(backupFolder, app.csvFileName));

            % Logic for actually committing the data - runFileConversion
            fileConvertObj = runFileConversion(app.CSVFilepathEditField.Value,app.RunDescriptionEditField.Value, app.RunNumberEditField.Value, app.DriverDropDown.Value, app.VehicleModelEditField.Value, app.TrackEditField.Value);

            % Get the new .mat filepath
            matFilePath = fileConvertObj.metadata.matFilePath;

            % Get the runSummary and lapSummary Tables and update app
            app = app.createSummary(matFilePath);
         

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DataCommitterLabel = uilabel(app.GridLayout);
            app.DataCommitterLabel.Text = 'Data Committer';
            app.UserInputsGridLayout = uigridlayout(app.GridLayout);
            app.CSVFilepathPanel = uipanel(app.UserInputsGridLayout);
            app.CSVFilepathEditFieldLabel = uilabel(app.CSVFilepathPanel);
            app.CSVFilepathEditFieldLabel.Text = 'CSV Filepath';
            app.CSVFilepathEditField = uieditfield(app.CSVFilepathPanel, 'text');
            app.ChooseCSVButton = uibutton(app.CSVFilepathPanel, 'push');
            app.ChooseCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVButtonPushed, true);
            app.ChooseCSVButton.Text = '...';
            app.RunDescriptionPanel = uipanel(app.UserInputsGridLayout);
            app.RunDescriptionEditFieldLabel = uilabel(app.RunDescriptionPanel);
            app.RunDescriptionEditFieldLabel.Text = 'Run Description';
            app.RunDescriptionEditField = uieditfield(app.RunDescriptionPanel, 'text');
            app.VehicleModelPanel = uipanel(app.UserInputsGridLayout);
            app.VehicleModelEditFieldLabel = uilabel(app.VehicleModelPanel);
            app.VehicleModelEditFieldLabel.Text = 'Vehicle Model';
            app.VehicleModelEditField = uieditfield(app.VehicleModelPanel, 'text');
            app.VehicleModelEditField.Placeholder = 'SimpleVehicle_Drag';
            app.TrackEditFieldLabel = uilabel(app.VehicleModelPanel);
            app.TrackEditFieldLabel.Text = 'Track';
            app.TrackEditField = uieditfield(app.VehicleModelPanel, 'text');
            app.RunDetailsPanel = uipanel(app.UserInputsGridLayout);
            app.RunNumberEditFieldLabel = uilabel(app.RunDetailsPanel);
            app.RunNumberEditFieldLabel.Text = 'Run Number';
            app.RunNumberEditField = uieditfield(app.RunDetailsPanel, 'numeric');
            app.RunNumberEditField.Limits = [0 Inf];
            app.DriverDropDownLabel = uilabel(app.RunDetailsPanel);
            app.DriverDropDownLabel.Text = 'Driver';
            app.DriverDropDown = uidropdown(app.RunDetailsPanel);
            app.DriverDropDown.Items = {'SP', 'AI_IP', 'AI_SV', 'PID', 'FFNN', 'LZ', 'BX', 'KP', 'NP'};
            app.DriverDropDown.Value = 'SP';
            app.CommitButton = uibutton(app.RunDetailsPanel, 'push');
            app.CommitButton.ButtonPushedFcn = createCallbackFcn(app, @CommitButtonPushed, true);
            app.CommitButton.Text = 'Commit';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.RunSummaryTab = uitab(app.TabGroup);
            app.RunSummaryTab.Title = 'Run Summary';
            app.RunSummaryGridLayout = uigridlayout(app.RunSummaryTab);
            app.RunSummaryTable = uitable(app.RunSummaryGridLayout);
            app.RunSummaryTable.ColumnName = {'Year'; 'Event'; 'Day'; 'Run'; 'Track'; 'Driver'; 'Description'; 'Laps'; 'Filename'; 'VehicleModel'; 'RunComments'};
            app.RunSummaryTable.RowName = {};
            app.RunSummaryTable.ColumnEditable = [false false false false false false false false false false true];
            app.LapSummaryTab = uitab(app.TabGroup);
            app.LapSummaryTab.Title = 'Lap Summary';
            app.LapSummaryGridLayout = uigridlayout(app.LapSummaryTab);
            app.LapSummaryTable = uitable(app.LapSummaryGridLayout);
            app.LapSummaryTable.ColumnName = {'Year'; 'Event'; 'Day'; 'Run'; 'Track'; 'Driver'; 'LapNumber'; 'LapTime'; 'LapType'; 'VehicleModel'; 'LapComment'};
            app.LapSummaryTable.RowName = {};
            app.LapSummaryTable.ColumnEditable = [false false false false false false false false false false true];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataCommitter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end